classdef SimuladorVehicular < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        EscenarioDensidadTab          matlab.ui.container.Tab
        TabGroup2_2                   matlab.ui.container.TabGroup
        GraficasDiferencialesTab      matlab.ui.container.Tab
        ResultadosPanel_6             matlab.ui.container.Panel
        UIAxes_11                     matlab.ui.control.UIAxes
        UIAxes_10                     matlab.ui.control.UIAxes
        UIAxes_9                      matlab.ui.control.UIAxes
        GraficasIntegralesTab         matlab.ui.container.Tab
        ResultadosPanel_7             matlab.ui.container.Panel
        UIAxes_13                     matlab.ui.control.UIAxes
        UIAxes_12                     matlab.ui.control.UIAxes
        error_k                       matlab.ui.control.Label
        DensidadesLabel               matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        hora                          matlab.ui.control.RangeSlider
        HOrasLabel                    matlab.ui.control.Label
        SimularButton                 matlab.ui.control.Button
        Kc                            matlab.ui.control.NumericEditField
        DensidaddecongestinLabel      matlab.ui.control.Label
        V1                            matlab.ui.control.NumericEditField
        VelocidadmediaaflujolibreenkmhEditFieldLabel  matlab.ui.control.Label
        EscenarioResorteTab           matlab.ui.container.Tab
        vi                            matlab.ui.control.NumericEditField
        MasapropmediodelosvehiculosKgLabel_4  matlab.ui.control.Label
        h                             matlab.ui.control.NumericEditField
        MasapropmediodelosvehiculosKgLabel_3  matlab.ui.control.Label
        h0                            matlab.ui.control.NumericEditField
        MasapropmediodelosvehiculosKgLabel_2  matlab.ui.control.Label
        masa                          matlab.ui.control.NumericEditField
        MasapropmediodelosvehiculosKgLabel  matlab.ui.control.Label
        SimularButton_2               matlab.ui.control.Button
        ResultadosPanel               matlab.ui.container.Panel
        UIAxes_5                      matlab.ui.control.UIAxes
        UIAxes_4                      matlab.ui.control.UIAxes
        EscenarioColasTab             matlab.ui.container.Tab
        ResultadosPanel_4             matlab.ui.container.Panel
        res_colas                     matlab.ui.control.Label
        t_eval                        matlab.ui.control.NumericEditField
        MasapropmediodelosvehiculosKgLabel_10  matlab.ui.control.Label
        n_eval                        matlab.ui.control.NumericEditField
        MasapropmediodelosvehiculosKgLabel_9  matlab.ui.control.Label
        v_servicio                    matlab.ui.control.NumericEditField
        MasapropmediodelosvehiculosKgLabel_8  matlab.ui.control.Label
        vehiculos_hora                matlab.ui.control.NumericEditField
        MasapropmediodelosvehiculosKgLabel_7  matlab.ui.control.Label
        SimularButton_4               matlab.ui.control.Button
        EscenarioRelojVirtualTab      matlab.ui.container.Tab
        error_reloj                   matlab.ui.control.Label
        dur_semaforo                  matlab.ui.control.NumericEditField
        MasapropmediodelosvehiculosKgLabel_11  matlab.ui.control.Label
        TabGroup2                     matlab.ui.container.TabGroup
        GraficasTab                   matlab.ui.container.Tab
        ResultadosPanel_3             matlab.ui.container.Panel
        res_cantidad                  matlab.ui.control.Label
        res_sema                      matlab.ui.control.Label
        UIAxes_7                      matlab.ui.control.UIAxes
        UIAxes_6                      matlab.ui.control.UIAxes
        EventosTab                    matlab.ui.container.Tab
        res_eventos                   matlab.ui.control.Label
        SemforoenVerdeButtonGroup     matlab.ui.container.ButtonGroup
        Semforo2Button                matlab.ui.control.RadioButton
        Semforo1Button                matlab.ui.control.RadioButton
        m_veh                         matlab.ui.control.NumericEditField
        MasapropmediodelosvehiculosKgLabel_6  matlab.ui.control.Label
        tiempo                        matlab.ui.control.NumericEditField
        MasapropmediodelosvehiculosKgLabel_5  matlab.ui.control.Label
        SimularButton_3               matlab.ui.control.Button
        EscenarioMonteCarloTab        matlab.ui.container.Tab
        ResultadosPanel_5             matlab.ui.container.Panel
        desviacion_espera             matlab.ui.control.Label
        media_espera                  matlab.ui.control.Label
        UIAxes_8                      matlab.ui.control.UIAxes
        NmerodesimulacionesEditField  matlab.ui.control.NumericEditField
        NmerodesimulacionesEditFieldLabel  matlab.ui.control.Label
        MediadetiempoentrellegadasdepasajerosenminutosEditField_3  matlab.ui.control.NumericEditField
        MediadetiempoquetardaelautobsenrecogerpasajerosenminutosLabel  matlab.ui.control.Label
        MediadetiempoentrellegadasdeautobusesenminutosEditField  matlab.ui.control.NumericEditField
        MediadetiempoentrellegadasdeautobusesenminutosEditFieldLabel  matlab.ui.control.Label
        MediadetiempoentrellegadasdepasajerosenminutosEditField  matlab.ui.control.NumericEditField
        MediadetiempoentrellegadasdepasajerosenminutosEditFieldLabel  matlab.ui.control.Label
        SimularButton_5               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            dt = zeros(1,24,"double");
            app.UITable.Data= dt;
        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
                app.error_k.Text = '';

                % Parámetros fijos
                V1 = app.V1.Value;  % Velocidad media a flujo libre en km/h
                Kc = app.Kc.Value;  % Densidad de congestión en veh/km
                
                horas1 = app.hora.Value;

                % Generar densidades aleatorias para cada hora del día (6am a 6pm)
                horas = horas1(1):horas1(2);  % Horas de 6am a 6pm
                k_vals = app.UITable.Data;  % Densidades aleatorias entre 20 y 90 veh/km
                k_vals = k_vals(horas1(1)+1:horas1(2)+1);
                    
                for ki = k_vals;
                    if ki > Kc
                        app.error_k.Text = 'Error: Las densidades no pueden ser mayores a la densidad de congestionamiento.';
                        return;
                    end
                end

                % Calcular el flujo vehicular q para cada hora
                q_vals = V1 * k_vals - (V1 / Kc) * k_vals.^2;

                % DN/Dt
                dsolve('DN = V1 *k_vals - (V1 / Kc) * k_vals^2', 'N(0)=0', 'horas');
                N = eval(vectorize(ans));

                
                % Calcular la velocidad media espacial V_e para cada hora
                Ve_vals = V1 - (V1 / Kc) * k_vals;

                % Dl/Dt
                dsolve('Dl = V1 - (V1/Kc) * k_vals', 'l(0)=0', 'horas');
                l = eval(vectorize(ans));


                % Gráfico del flujo vehicular
                %subplot(3, 1, 1);
                plot(app.UIAxes_9, horas, q_vals, 'go-', 'MarkerFaceColor', 'g');
                xlabel(app.UIAxes_9, 'Hora del día');
                ylabel(app.UIAxes_9, 'Flujo vehicular q (veh/h)');
                title(app.UIAxes_9, 'Flujo Vehicular a lo Largo del Día');
                
                % Gráfico de la velocidad media espacial
                %subplot(3, 1, 2);
                plot(app.UIAxes_10, horas, Ve_vals, 'bo-', 'MarkerFaceColor', 'b');
                xlabel(app.UIAxes_10, 'Hora del día');
                ylabel(app.UIAxes_10, 'Velocidad media V_e (km/h)');
                title(app.UIAxes_10, 'Velocidad Media Espacial a lo Largo del Día');
                
                % Gráfico de la densidad vehicular
                %subplot(3, 1, 3);
                plot(app.UIAxes_11, horas, k_vals, 'ro-', 'MarkerFaceColor', 'r');
                xlabel(app.UIAxes_11, 'Hora del día');
                ylabel(app.UIAxes_11, 'Densidad vehicular k (veh/km)');
                title(app.UIAxes_11, 'Densidad Vehicular a lo Largo del Día');

                
                % Gráfico de la distancia recorrida
                plot(app.UIAxes_12, horas, l);
                xlabel(app.UIAxes_12, 'Tiempo (h)');
                ylabel(app.UIAxes_12, 'distancia recorrida (km)');

                % Gráfico de la cantidad de vehiculos
                plot(app.UIAxes_13, horas, N);
                xlabel(app.UIAxes_13, 'Tiempo (h)');
                ylabel(app.UIAxes_13, 'Vehiculos (N)');
        end

        % Callback function
        function SimularButton_2Pushed(app, event)
        
        end

        % Button pushed function: SimularButton_2
        function SimularButton_2Pushed2(app, event)
            % Parámetros del modelo
            m = app.masa.Value; % Inercia del sistema (en unidades normalizadas)
            b = 6000; % Coeficiente de amortiguamiento (en N·s/m)
            k = 300; % Constante del resorte (en N/m)
            h0 = app.h0.Value; % Distancia óptima entre vehículos (en metros)
            
            % Condiciones iniciales
            h_initial = app.h.Value; % Distancia inicial entre vehículos (en metros)
            v_initial = app.vi.Value; % Velocidad inicial (en m/s)
            
            % Intervalo de tiempo
            tspan = [0 200];
            
            % Ecuaciones de movimiento
            % y(1) = h (distancia entre vehículos), y(2) = v (velocidad)
            ode = @(t, y) [y(2); - (b/m) * y(2) - (k/m) * (y(1) - h0)];
            
            % Resolución de las ecuaciones de movimiento
            [t, y] = ode45(ode, tspan, [h_initial v_initial]);
            
            % Visualización de la distancia entre vehículos en función del tiempo
            plot(app.UIAxes_4, t, y(:, 1), 'b', 'LineWidth', 2);
            xlabel(app.UIAxes_4, 'Tiempo (s)');
            ylabel(app.UIAxes_4, 'Distancia entre vehículos (m)');
            title(app.UIAxes_4, 'Distancia entre Vehículos vs Tiempo');
            
            % Visualización de la velocidad en función del tiempo
            plot(app.UIAxes_5, t, y(:, 2), 'r', 'LineWidth', 2);
            xlabel(app.UIAxes_5, 'Tiempo (s)');
            ylabel(app.UIAxes_5, 'Velocidad (m/s)');
            title(app.UIAxes_5, 'Velocidad vs Tiempo');
        end

        % Button pushed function: SimularButton_3
        function SimularButton_3Pushed(app, event)
            % Tráfico de una intersección
            app.error_reloj.Text = '';
            semaforo1 = app.Semforo2Button.Value; % 1 = verde, 0 = rojo
            semaforo2 = app.Semforo1Button.Value; % 1 = verde, 0 = rojo
            vehiculos1 = 0;
            vehiculos2 = 0;
            tiempo_total = app.tiempo.Value; % tiempo de la simulación 
            if tiempo_total <= 0
                app.error_reloj.Text = 'Error: El tiempo de simulación debe ser mayor a 0';
                return;
            end
            reloj_virtual = 0;
            eventos = [];
            eventos_str = []; % descripcion


            % num_cambios_estado = 4;
            
            max_vehiculos = app.m_veh.Value; % cantidad máxima de veces que puede llegar vehiculos a la cola del semáforo
            
            
            vehiculos_tiempos = sort(randi([1 tiempo_total], 1, max_vehiculos));
            
            
            tiempo = 0:tiempo_total;
            cantidad_vehiculos1 = zeros(1, tiempo_total + 1);
            cantidad_vehiculos2 = zeros(1, tiempo_total + 1);
            
            estado_semaforo1 = zeros(1, tiempo_total + 1);
            estado_semaforo2 = zeros(1, tiempo_total + 1);
            
            disp('Comienza la simulación');
            
            for t = 0:tiempo_total;
                reloj_virtual = t;
                
                % cambio del semáforo
                if mod(reloj_virtual, app.dur_semaforo.Value) == 0
                    eventos = [eventos; reloj_virtual];
                    if semaforo1 == 1
                        eventos_str{end+1} = sprintf('Minuto %d: cambio a verde el semáforo 2', reloj_virtual);
                        semaforo1 = 0;
                        semaforo2 = 1;
                    else
                        eventos_str{end+1} = sprintf('Minuto %d: cambio a verde el semáforo 1', reloj_virtual);
                        semaforo1 = 1;
                        semaforo2 = 0;
                    end
                end
                
                % vehiculos que llegan al semáforo
                if ismember(reloj_virtual, vehiculos_tiempos)
                    if semaforo2 == 1
                        vehiculos1 = vehiculos1 + 2;
                        eventos = [eventos; reloj_virtual];
                        eventos_str{end+1} = sprintf('Minuto %d: llegan 2 vehiculos al semáforo 1', reloj_virtual);
                    else
                        vehiculos2 = vehiculos2 + 2;
                        eventos = [eventos; reloj_virtual];
                        eventos_str{end+1} = sprintf('Minuto %d: llegan 2 vehiculos al semáforo 2', reloj_virtual);
                    end
                end
            
                % vehiculos que salen del semáforo
                if semaforo1 == 1 && vehiculos1 >= 1
                    vehiculos1 = vehiculos1 - 1;
                    eventos = [eventos; reloj_virtual];
                    eventos_str{end+1} = sprintf('Minuto %d: salen 1 vehiculos del semáforo 1', reloj_virtual);
                elseif semaforo2 == 1 && vehiculos2 >= 1
                    vehiculos2 = vehiculos2 - 1;
                    eventos = [eventos; reloj_virtual];
                    eventos_str{end+1} = sprintf('Minuto %d: salen 1 vehiculos del semáforo 2', reloj_virtual);
                end
            
                
                cantidad_vehiculos1(t+1) = vehiculos1;
                cantidad_vehiculos2(t+1) = vehiculos2;
            
                estado_semaforo1(t+1) = semaforo1;
                estado_semaforo2(t+1) = semaforo2;
            end
            
            
            app.res_sema.Text = sprintf('Semáforo 1: %d - Semáforo 2: %d\n', semaforo1, semaforo2);
            app.res_cantidad.Text = sprintf('Cantidad de vehiculos del semáforo 1: %d - Cantidad de vehiculos del semáforo 2: %d\n', vehiculos1, vehiculos2);

            disp('Eventos durante el tráfico')
            event = '';
            for i= 1:length(eventos_str)
                event = strcat(event, ' ', eventos_str{i}, '\n');
            end
            app.res_eventos.Text = sprintf(event);


            plot(app.UIAxes_6, tiempo, cantidad_vehiculos1, '-o', 'LineWidth', 2, 'DisplayName', 'Cantidad de vehiculos 1');
            hold(app.UIAxes_6, 'on');
            plot(app.UIAxes_6, tiempo, cantidad_vehiculos2, '-x', 'LineWidth', 2, 'DisplayName', 'Cantidad de vehiculos 2');
            ylabel(app.UIAxes_6, 'Vehiculos');
            title(app.UIAxes_6, 'Evolución de la cantidad de vehiculos');
            legend(app.UIAxes_6, 'show');
            hold(app.UIAxes_6, 'off');

            stem(app.UIAxes_7, tiempo, estado_semaforo1, 'DisplayName', 'Semáforo 1');
            hold(app.UIAxes_7);
            stem(app.UIAxes_7, tiempo, estado_semaforo2, 'DisplayName', 'Semáforo 2');
            title(app.UIAxes_7, 'Evolución de los semáforos');
            ylabel(app.UIAxes_7, 'Semáforos Verde/Rojo');
            xlabel(app.UIAxes_7, 'Minutos');
            legend(app.UIAxes_7, 'show');
            hold(app.UIAxes_7, 'off');
        end

        % Button pushed function: SimularButton_4
        function SimularButton_4Pushed(app, event)
        function [P_n, n_avg, sigma_avg, T_s_avg, T_w_avg, P_utilizacion, P_inactivo, P_t, P_Ts_t, P_Tq_t] = calcular_metricas(lambda, mu, n, t)
            % Probabilidad de tener exactamente n vehículos en el sistema
            P_n = (lambda / mu)^n * (1 - lambda / mu);
        
            % Número promedio de vehículos en el sistema
            n_avg = lambda / (mu - lambda);
        
            % Longitud promedio de la línea de espera
            sigma_avg = lambda^2 / (mu * (mu - lambda));
        
            % Tiempo promedio gastado en el sistema
            T_s_avg = 1 / (mu - lambda);
        
            % Tiempo promedio de espera en la fila
            T_w_avg = lambda / (mu * (mu - lambda));
        
            % Porcentaje de utilización del servicio
            P_utilizacion = (lambda / mu) * 100;
        
            % Porcentaje de encontrar el sistema inactivo
            P_inactivo = (1 - lambda / mu) * 100;
        
            % Probabilidad de que un cliente gaste exactamente un tiempo t en el sistema
            P_t = (mu - lambda) * exp((lambda - mu) * t);
        
            % Probabilidad de que un cliente gaste un tiempo menor o igual a t en el sistema
            P_Ts_t = 1 - exp(-(1 - lambda/mu) * t * mu);
        
            % Probabilidad de tener que esperar un tiempo t ó menos en la fila
            P_Tq_t = 1 - (lambda / mu) * exp(-(1 - lambda/mu) * t * mu);
        end
        
        % Ejemplo de uso:
        lambda = app.vehiculos_hora.Value / 3600;  % Tasa de llegadas en vehículos por segundo (30 vehículos por hora)
        mu = app.v_servicio.Value / 3600;      % Tasa de servicio en vehículos por segundo (45 vehículos por hora)
        n = app.n_eval.Value;               % Número de vehículos en el sistema a evaluar
        t = app.t_eval.Value;              % Tiempo a evaluar en segundos (1 minuto)
        if app.vehiculos_hora.Value >= app.v_servicio.Value
            app.res_colas.Text = 'La cantidad de llegada de Vehiculos debe ser menor al número de servicios.';
            return;
        end
        
        % Llamar a la función para calcular las métricas
        [P_n, n_avg, sigma_avg, T_s_avg, T_w_avg, P_utilizacion, P_inactivo, P_t, P_Ts_t, P_Tq_t] = calcular_metricas(lambda, mu, n, t);
        
        % Mostrar resultados
        app.res_colas.Text = sprintf(['Probabilidad de tener exactamente %d vehículos en el sistema: %.2f%%\n' ...
            'Número promedio de vehículos en el sistema: %.2f%\n' ...
            'Longitud promedio de la línea de espera: %.2f%\n' ...
            'Tiempo promedio gastado en el sistema: %.2f segundos%\n' ...
            'Tiempo promedio de espera en la fila: %.2f segundos%\n' ...
            'Porcentaje de utilización del servicio: %.2f%%\n' ...
            'Porcentaje de encontrar el sistema inactivo: %.2f%%\n' ...
            'Probabilidad de gastar exactamente %d segundos en el sistema: %.2f%%\n' ...
            'Probabilidad de gastar %d segundos o menos en el sistema: %.2f%%\n' ...
            'Probabilidad de gastar %d segundos o menos en la fila: %.2f%%\n'], ...
            n, P_n*100, n_avg, sigma_avg, T_s_avg, T_w_avg, P_utilizacion, P_inactivo, t, P_t*100, t, P_Ts_t*100, t, P_Tq_t*100);

        end

        % Button pushed function: SimularButton_5
        function SimularButton_5Pushed(app, event)
            % Parámetros de la simulación
            lambda_pasajeros = app.MediadetiempoentrellegadasdepasajerosenminutosEditField.Value; % Media de tiempo entre llegadas de pasajeros (en minutos)
            lambda_autobus = app.MediadetiempoentrellegadasdeautobusesenminutosEditField.Value; % Media de tiempo entre llegadas de autobuses (en minutos)
            mu_servicio = app.MediadetiempoentrellegadasdepasajerosenminutosEditField_3.Value;     % Media de tiempo que tarda el autobús en recoger pasajeros (en minutos)
            sigma_servicio = 0.1; % Desviación estándar del tiempo de servicio (en minutos)
            N = app.NmerodesimulacionesEditField.Value;   % Número de simulaciones
            num_pasajeros = 100; % Número de pasajeros simulados
            
            % Inicialización del vector para almacenar los tiempos de espera de los pasajeros
            tiempos_espera = zeros(N, 1);
            
            % Simulación
            for i = 1:N
                % Generar los tiempos de llegada de los pasajeros (distribución exponencial)
                llegadas_pasajeros = cumsum(exprnd(lambda_pasajeros, num_pasajeros, 1)); 
            
                % Generar los tiempos de llegada de los autobuses (distribución exponencial)
                llegadas_autobuses = cumsum(exprnd(lambda_autobus, 20, 1)); % 20 autobuses
            
                % Inicializar el tiempo de espera acumulado
                espera_total = 0;
            
                % Calcular el tiempo de espera para cada pasajero
                for j = 1:length(llegadas_pasajeros)
                    % Encuentra el primer autobús que llega después de que el pasajero llegue
                    autobus_disponible = find(llegadas_autobuses >= llegadas_pasajeros(j), 1);
            
                    if isempty(autobus_disponible)
                        % Si no hay más autobuses, el pasajero espera indefinidamente
                        espera = NaN;
                    else
                        % El tiempo de espera es la diferencia entre la llegada del autobús y la llegada del pasajero
                        espera = llegadas_autobuses(autobus_disponible) - llegadas_pasajeros(j);
                    end
            
                    % Sumar el tiempo de espera a la cuenta total (si es NaN, lo ignoramos)
                    if ~isnan(espera)
                        espera_total = espera_total + espera;
                    end
                end
            
                % Calcular el tiempo de espera promedio para esta simulación
                tiempos_espera(i) = espera_total / length(llegadas_pasajeros);
            end
            
            % Calcular la media y la desviación estándar de los tiempos de espera
            app.media_espera.Text = sprintf('Media de tiempo de espera: %.2f minuto(s)', mean(tiempos_espera, 'omitnan'));
            app.desviacion_espera.Text = sprintf('Desviación estandar de tiempo de espera: %.2f minuto(s)', std(tiempos_espera, 'omitnan'));
            
            % Graficar la distribución de los tiempos de espera
            histogram(app.UIAxes_8, tiempos_espera, 'Normalization', 'pdf');
            xlabel(app.UIAxes_8, 'Tiempo medio de espera (minutos)');
            ylabel(app.UIAxes_8, 'Frecuencia');
            title(app.UIAxes_8, 'Distribución del tiempo medio de espera en la parada de autobús');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EscenarioDensidadTab = uitab(app.TabGroup);
            app.EscenarioDensidadTab.Title = 'Escenario Densidad';
            app.VelocidadmediaaflujolibreenkmhEditFieldLabel = uilabel(app.EscenarioDensidadTab);
            app.VelocidadmediaaflujolibreenkmhEditFieldLabel.Text = 'Velocidad media a flujo libre en km/h';
            app.V1 = uieditfield(app.EscenarioDensidadTab, 'numeric');
            app.DensidaddecongestinLabel = uilabel(app.EscenarioDensidadTab);
            app.DensidaddecongestinLabel.Text = 'Densidad de congestión';
            app.Kc = uieditfield(app.EscenarioDensidadTab, 'numeric');
            app.SimularButton = uibutton(app.EscenarioDensidadTab, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.HOrasLabel = uilabel(app.EscenarioDensidadTab);
            app.HOrasLabel.Text = 'Horas';
            app.hora = uislider(app.EscenarioDensidadTab, 'range');
            app.hora.Limits = [0 23];
            app.hora.Step = 1;
            app.hora.Value = [0 23];
            app.UITable = uitable(app.EscenarioDensidadTab);
            app.UITable.ColumnName = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'};
            app.UITable.RowName = {'Densidad'};
            app.UITable.ColumnEditable = [true true true true true true true true true true true true true true true true true true true true true true true true];
            app.DensidadesLabel = uilabel(app.EscenarioDensidadTab);
            app.DensidadesLabel.Text = 'Densidades';
            app.error_k = uilabel(app.EscenarioDensidadTab);
            app.error_k.Text = '';
            app.TabGroup2_2 = uitabgroup(app.EscenarioDensidadTab);
            app.GraficasDiferencialesTab = uitab(app.TabGroup2_2);
            app.GraficasDiferencialesTab.Title = 'Graficas Diferenciales';
            app.ResultadosPanel_6 = uipanel(app.GraficasDiferencialesTab);
            app.ResultadosPanel_6.Title = 'Resultados';
            app.UIAxes_9 = uiaxes(app.ResultadosPanel_6);
            title(app.UIAxes_9, 'Title')
            xlabel(app.UIAxes_9, 'X')
            ylabel(app.UIAxes_9, 'Y')
            zlabel(app.UIAxes_9, 'Z')
            app.UIAxes_10 = uiaxes(app.ResultadosPanel_6);
            title(app.UIAxes_10, 'Title')
            xlabel(app.UIAxes_10, 'X')
            ylabel(app.UIAxes_10, 'Y')
            zlabel(app.UIAxes_10, 'Z')
            app.UIAxes_11 = uiaxes(app.ResultadosPanel_6);
            title(app.UIAxes_11, 'Title')
            xlabel(app.UIAxes_11, 'X')
            ylabel(app.UIAxes_11, 'Y')
            zlabel(app.UIAxes_11, 'Z')
            app.GraficasIntegralesTab = uitab(app.TabGroup2_2);
            app.GraficasIntegralesTab.Title = 'Graficas Integrales';
            app.ResultadosPanel_7 = uipanel(app.GraficasIntegralesTab);
            app.ResultadosPanel_7.Title = 'Resultados';
            app.UIAxes_12 = uiaxes(app.ResultadosPanel_7);
            title(app.UIAxes_12, 'Title')
            xlabel(app.UIAxes_12, 'X')
            ylabel(app.UIAxes_12, 'Y')
            zlabel(app.UIAxes_12, 'Z')
            app.UIAxes_13 = uiaxes(app.ResultadosPanel_7);
            title(app.UIAxes_13, 'Title')
            xlabel(app.UIAxes_13, 'X')
            ylabel(app.UIAxes_13, 'Y')
            zlabel(app.UIAxes_13, 'Z')
            app.EscenarioResorteTab = uitab(app.TabGroup);
            app.EscenarioResorteTab.Title = 'Escenario Resorte';
            app.ResultadosPanel = uipanel(app.EscenarioResorteTab);
            app.ResultadosPanel.Title = 'Resultados';
            app.UIAxes_4 = uiaxes(app.ResultadosPanel);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.ResultadosPanel);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.SimularButton_2 = uibutton(app.EscenarioResorteTab, 'push');
            app.SimularButton_2.ButtonPushedFcn = createCallbackFcn(app, @SimularButton_2Pushed2, true);
            app.SimularButton_2.Text = 'Simular';
            app.MasapropmediodelosvehiculosKgLabel = uilabel(app.EscenarioResorteTab);
            app.MasapropmediodelosvehiculosKgLabel.Text = 'Masa propmedio de los vehiculos (Kg)';
            app.masa = uieditfield(app.EscenarioResorteTab, 'numeric');
            app.MasapropmediodelosvehiculosKgLabel_2 = uilabel(app.EscenarioResorteTab);
            app.MasapropmediodelosvehiculosKgLabel_2.Text = 'Distancia óptima entre vehiculos (m)';
            app.h0 = uieditfield(app.EscenarioResorteTab, 'numeric');
            app.MasapropmediodelosvehiculosKgLabel_3 = uilabel(app.EscenarioResorteTab);
            app.MasapropmediodelosvehiculosKgLabel_3.Text = 'Distancia entre vehiculos inicial (m)';
            app.h = uieditfield(app.EscenarioResorteTab, 'numeric');
            app.MasapropmediodelosvehiculosKgLabel_4 = uilabel(app.EscenarioResorteTab);
            app.MasapropmediodelosvehiculosKgLabel_4.Text = 'Velocidad inicial (m/s)';
            app.vi = uieditfield(app.EscenarioResorteTab, 'numeric');
            app.EscenarioColasTab = uitab(app.TabGroup);
            app.EscenarioColasTab.Title = 'Escenario Colas';
            app.SimularButton_4 = uibutton(app.EscenarioColasTab, 'push');
            app.SimularButton_4.ButtonPushedFcn = createCallbackFcn(app, @SimularButton_4Pushed, true);
            app.SimularButton_4.Text = 'Simular';
            app.MasapropmediodelosvehiculosKgLabel_7 = uilabel(app.EscenarioColasTab);
            app.MasapropmediodelosvehiculosKgLabel_7.Text = 'Cantidad de Vehiculos que llegan en 1 hora';
            app.vehiculos_hora = uieditfield(app.EscenarioColasTab, 'numeric');
            app.MasapropmediodelosvehiculosKgLabel_8 = uilabel(app.EscenarioColasTab);
            app.MasapropmediodelosvehiculosKgLabel_8.Text = 'Servicio de vehículos por hora';
            app.v_servicio = uieditfield(app.EscenarioColasTab, 'numeric');
            app.MasapropmediodelosvehiculosKgLabel_9 = uilabel(app.EscenarioColasTab);
            app.MasapropmediodelosvehiculosKgLabel_9.Text = 'Número de vehículos en el sistema a evaluar';
            app.n_eval = uieditfield(app.EscenarioColasTab, 'numeric');
            app.MasapropmediodelosvehiculosKgLabel_10 = uilabel(app.EscenarioColasTab);
            app.MasapropmediodelosvehiculosKgLabel_10.Text = 'Tiempo a evaluar en segundos';
            app.t_eval = uieditfield(app.EscenarioColasTab, 'numeric');
            app.ResultadosPanel_4 = uipanel(app.EscenarioColasTab);
            app.ResultadosPanel_4.Title = 'Resultados';
            app.res_colas = uilabel(app.ResultadosPanel_4);
            app.res_colas.Text = '';
            app.EscenarioRelojVirtualTab = uitab(app.TabGroup);
            app.EscenarioRelojVirtualTab.Title = 'Escenario Reloj Virtual';
            app.SimularButton_3 = uibutton(app.EscenarioRelojVirtualTab, 'push');
            app.SimularButton_3.ButtonPushedFcn = createCallbackFcn(app, @SimularButton_3Pushed, true);
            app.SimularButton_3.Text = 'Simular';
            app.MasapropmediodelosvehiculosKgLabel_5 = uilabel(app.EscenarioRelojVirtualTab);
            app.MasapropmediodelosvehiculosKgLabel_5.Text = 'Duración de la simulación (min)';
            app.tiempo = uieditfield(app.EscenarioRelojVirtualTab, 'numeric');
            app.MasapropmediodelosvehiculosKgLabel_6 = uilabel(app.EscenarioRelojVirtualTab);
            app.MasapropmediodelosvehiculosKgLabel_6.Text = 'Cantidad máxima de llegada de vehiculos';
            app.m_veh = uieditfield(app.EscenarioRelojVirtualTab, 'numeric');
            app.SemforoenVerdeButtonGroup = uibuttongroup(app.EscenarioRelojVirtualTab);
            app.SemforoenVerdeButtonGroup.Title = 'Semáforo en Verde';
            app.Semforo1Button = uiradiobutton(app.SemforoenVerdeButtonGroup);
            app.Semforo1Button.Text = 'Semáforo 1';
            app.Semforo1Button.Value = true;
            app.Semforo2Button = uiradiobutton(app.SemforoenVerdeButtonGroup);
            app.Semforo2Button.Text = 'Semáforo 2';
            app.TabGroup2 = uitabgroup(app.EscenarioRelojVirtualTab);
            app.GraficasTab = uitab(app.TabGroup2);
            app.GraficasTab.Title = 'Graficas';
            app.ResultadosPanel_3 = uipanel(app.GraficasTab);
            app.ResultadosPanel_3.Title = 'Resultados';
            app.UIAxes_6 = uiaxes(app.ResultadosPanel_3);
            title(app.UIAxes_6, 'Title')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_7 = uiaxes(app.ResultadosPanel_3);
            title(app.UIAxes_7, 'Title')
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            zlabel(app.UIAxes_7, 'Z')
            app.res_sema = uilabel(app.ResultadosPanel_3);
            app.res_sema.Text = '';
            app.res_cantidad = uilabel(app.ResultadosPanel_3);
            app.res_cantidad.Text = '';
            app.EventosTab = uitab(app.TabGroup2);
            app.EventosTab.Title = 'Eventos';
            app.res_eventos = uilabel(app.EventosTab);
            app.res_eventos.Text = '';
            app.MasapropmediodelosvehiculosKgLabel_11 = uilabel(app.EscenarioRelojVirtualTab);
            app.MasapropmediodelosvehiculosKgLabel_11.Text = 'Duracion del semáforo';
            app.dur_semaforo = uieditfield(app.EscenarioRelojVirtualTab, 'numeric');
            app.error_reloj = uilabel(app.EscenarioRelojVirtualTab);
            app.error_reloj.Text = '';
            app.EscenarioMonteCarloTab = uitab(app.TabGroup);
            app.EscenarioMonteCarloTab.Title = 'Escenario Monte Carlo';
            app.SimularButton_5 = uibutton(app.EscenarioMonteCarloTab, 'push');
            app.SimularButton_5.ButtonPushedFcn = createCallbackFcn(app, @SimularButton_5Pushed, true);
            app.SimularButton_5.Text = 'Simular';
            app.MediadetiempoentrellegadasdepasajerosenminutosEditFieldLabel = uilabel(app.EscenarioMonteCarloTab);
            app.MediadetiempoentrellegadasdepasajerosenminutosEditFieldLabel.Text = 'Media de tiempo entre llegadas de pasajeros (en minutos)';
            app.MediadetiempoentrellegadasdepasajerosenminutosEditField = uieditfield(app.EscenarioMonteCarloTab, 'numeric');
            app.MediadetiempoentrellegadasdeautobusesenminutosEditFieldLabel = uilabel(app.EscenarioMonteCarloTab);
            app.MediadetiempoentrellegadasdeautobusesenminutosEditFieldLabel.Text = 'Media de tiempo entre llegadas de autobuses (en minutos)';
            app.MediadetiempoentrellegadasdeautobusesenminutosEditField = uieditfield(app.EscenarioMonteCarloTab, 'numeric');
            app.MediadetiempoquetardaelautobsenrecogerpasajerosenminutosLabel = uilabel(app.EscenarioMonteCarloTab);
            app.MediadetiempoquetardaelautobsenrecogerpasajerosenminutosLabel.Text = 'Tiempo que tarda el autobús en recoger pasajeros (min)';
            app.MediadetiempoentrellegadasdepasajerosenminutosEditField_3 = uieditfield(app.EscenarioMonteCarloTab, 'numeric');
            app.NmerodesimulacionesEditFieldLabel = uilabel(app.EscenarioMonteCarloTab);
            app.NmerodesimulacionesEditFieldLabel.Text = 'Número de simulaciones';
            app.NmerodesimulacionesEditField = uieditfield(app.EscenarioMonteCarloTab, 'numeric');
            app.ResultadosPanel_5 = uipanel(app.EscenarioMonteCarloTab);
            app.ResultadosPanel_5.Title = 'Resultados';
            app.UIAxes_8 = uiaxes(app.ResultadosPanel_5);
            title(app.UIAxes_8, 'Title')
            xlabel(app.UIAxes_8, 'X')
            ylabel(app.UIAxes_8, 'Y')
            zlabel(app.UIAxes_8, 'Z')
            app.media_espera = uilabel(app.ResultadosPanel_5);
            app.media_espera.Text = '';
            app.desviacion_espera = uilabel(app.ResultadosPanel_5);
            app.desviacion_espera.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimuladorVehicular

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end