classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        EscenarioDensidadTab      matlab.ui.container.Tab
        Panel                     matlab.ui.container.Panel
        UIAxes_3                  matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
        SimularButton             matlab.ui.control.Button
        Kc                        matlab.ui.control.NumericEditField
        DensidaddecongestinLabel  matlab.ui.control.Label
        k                         matlab.ui.control.NumericEditField
        VelocidadmediaaflujolibreenkmhEditFieldLabel  matlab.ui.control.Label
        Escenario2Tab             matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
            
                    % Parámetros fijos
                    V1 = app.k.Value;  % Velocidad media a flujo libre en km/h
                    Kc = app.Kc.Value;  % Densidad de congestión en veh/km
                    
                    % Generar densidades aleatorias para cada hora del día (6am a 6pm)
                    horas = 6:18;  % Horas de 6am a 6pm
                    k_vals = 20 + (Kc-20) * rand(1, length(horas));  % Densidades aleatorias entre 20 y 90 veh/km
                    
                    % Calcular el flujo vehicular q para cada hora
                    q_vals = V1 * k_vals - (V1 / Kc) * k_vals.^2;
                    
                    % Calcular la velocidad media espacial V_e para cada hora
                    Ve_vals = V1 - (V1 / Kc) * k_vals;
                    
                    
                    % Gráfico del flujo vehicular
                    %subplot(3, 1, 1);
                    plot(app.UIAxes, horas, q_vals, 'go-', 'MarkerFaceColor', 'g');
                    xlabel(app.UIAxes, 'Hora del día');
                    ylabel(app.UIAxes, 'Flujo vehicular q (veh/h)');
                    title(app.UIAxes, 'Flujo Vehicular a lo Largo del Día');
                    
                    % Gráfico de la velocidad media espacial
                    %subplot(3, 1, 2);
                    plot(app.UIAxes_2, horas, Ve_vals, 'bo-', 'MarkerFaceColor', 'b');
                    xlabel(app.UIAxes_2, 'Hora del día');
                    ylabel(app.UIAxes_2, 'Velocidad media V_e (km/h)');
                    title(app.UIAxes_2, 'Velocidad Media Espacial a lo Largo del Día');
                    
                    % Gráfico de la densidad vehicular
                    %subplot(3, 1, 3);
                    plot(app.UIAxes_3, horas, k_vals, 'ro-', 'MarkerFaceColor', 'r');
                    xlabel(app.UIAxes_3, 'Hora del día');
                    ylabel(app.UIAxes_3, 'Densidad vehicular k (veh/km)');
                    title(app.UIAxes_3, 'Densidad Vehicular a lo Largo del Día');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EscenarioDensidadTab = uitab(app.TabGroup);
            app.EscenarioDensidadTab.Title = 'Escenario Densidad';
            app.VelocidadmediaaflujolibreenkmhEditFieldLabel = uilabel(app.EscenarioDensidadTab);
            app.VelocidadmediaaflujolibreenkmhEditFieldLabel.Text = 'Velocidad media a flujo libre en km/h';
            app.k = uieditfield(app.EscenarioDensidadTab, 'numeric');
            app.DensidaddecongestinLabel = uilabel(app.EscenarioDensidadTab);
            app.DensidaddecongestinLabel.Text = 'Densidad de congestión';
            app.Kc = uieditfield(app.EscenarioDensidadTab, 'numeric');
            app.SimularButton = uibutton(app.EscenarioDensidadTab, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.Panel = uipanel(app.EscenarioDensidadTab);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.Panel);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.Panel);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.Escenario2Tab = uitab(app.TabGroup);
            app.Escenario2Tab.Title = 'Escenario 2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end